classdef DC_predictor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DCViewer                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        IDLonlyCheckBox           matlab.ui.control.CheckBox
        saveresultsButton         matlab.ui.control.Button
        NowloadfileLabel          matlab.ui.control.Label
        deadtime                  matlab.ui.control.NumericEditField
        ApplyDeadtimeButton       matlab.ui.control.Button
        Binarize                  matlab.ui.container.ButtonGroup
        NoBin                     matlab.ui.control.RadioButton
        YesBin                    matlab.ui.control.RadioButton
        FirstLoadmodelLabel       matlab.ui.control.Label
        LoadmodelButton           matlab.ui.control.Button
        dt                        matlab.ui.control.NumericEditField
        CustomdtsLabel            matlab.ui.control.Label
        duration                  matlab.ui.control.Knob
        start                     matlab.ui.control.Knob
        ToSlider                  matlab.ui.control.Slider
        ToLabel                   matlab.ui.control.Label
        FromSlider                matlab.ui.control.Slider
        FromSliderLabel           matlab.ui.control.Label
        xmax                      matlab.ui.control.NumericEditField
        xmaxEditFieldLabel        matlab.ui.control.Label
        xmin                      matlab.ui.control.NumericEditField
        xminEditFieldLabel        matlab.ui.control.Label
        DatacolumnsButtonGroup    matlab.ui.container.ButtonGroup
        ThreePlot                 matlab.ui.control.RadioButton
        TwoPlot                   matlab.ui.control.RadioButton
        OnePlot                   matlab.ui.control.RadioButton
        Notes                     matlab.ui.control.EditField
        DecimatetoEditField       matlab.ui.control.NumericEditField
        DecimatetoEditFieldLabel  matlab.ui.control.Label
        LoaddatafileButton        matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        extraAxis                 matlab.ui.control.UIAxes
        idealAxis                 matlab.ui.control.UIAxes
        rawAxis                   matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        decim % Level of decimation. Default =1 = no decimation!
        ADDTIME % Do we need to add a time column? true or false
        FILELOADED
        FileName
        newscale
        maxtime
        minr
        maxr
        curfile
        modelLOADED
        model
        deadpts
        rawdata % Just incase we make changes. Keep original to decimate etc.
        Property16 % Description
    end
    
    properties (Access = public)
        data %the main dataset
        si %the datas sample interval; public version of dt. should always match!!
    end
    
    methods (Access = private)
        
        function results = plot1ch(app)
            app.data =[linspace(0,length(data(:,1)),length(data))',data(:,1)];
            app.maxtime=max(data(:,1));
            app.xmin.Value=min(data(:,1));
            app.xmax.Value=max(data(:,1));          
            plot(app.rawAxis,data(:,1),data(:,2));
            app.rawAxis.YLim=[0,max(data(:,2)*1.25)];
            results=0;
        end
       
        
        function plot2ch(app)  
            %start by preparing to plot
            if app.ADDTIME==true
                addtime(app);
            end
            app.maxtime=max(app.data(:,1));
            %%Todo decimate it
            factor=app.decim;
            %do the actual plot
            [minr, maxr, YLim]=fullplot(app);  
            app.xmin.Value=minr;
            app.xmax.Value=maxr;  
            %legacy bug. would do nothing?
            app.idealAxis.YLim=YLim;   
        end
        
        function addtime(app)
            app.data =[infertime(app),app.data(:,1:end)];
        end
        
        function results = setaxis(app,minr,maxr)
            app.rawAxis.XLim=[minr, maxr];
            app.idealAxis.XLim=[minr, maxr];
            app.extraAxis.XLim=[minr, maxr];           
        end

        
        function results = decimate(app)
            if app.FILELOADED==false
                results=1;
                return;
            end
            if app.decim > 1
                app.data=downsample(app.rawdata,int64(app.decim)); 
            elseif app.decim < 1
                factor=int64(1/app.decim);
                r=length(app.rawdata(:,1));
                c=length(app.rawdata(1,:));
                tmpdata=zeros([r*factor,c]);
                for ii=1:length(app.rawdata(1,:))
                    tmpdata(:,ii)=interp(app.rawdata(:,ii),factor); 
                end
                app.data=tmpdata;
            end
            results=0;
        end
                
        function results = postfileloaded(app)
            app.Notes.Value=string(app.FileName);
            app.DecimatetoEditField.Visible=true;
            app.DecimatetoEditFieldLabel.Visible=true;
            app.ApplyDeadtimeButton.Visible=true;
            app.deadtime.Visible=true;
            app.saveresultsButton.Visible=false;
            app.IDLonlyCheckBox.Visible=false;
        end
        
        function results = chooseplot(app)
            if app.OnePlot.Value==true
                %Todo: Not really sorted this yet.
                plot1ch(app);
            else 
                plot2ch(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.newscale=false;
            app.si=app.dt.Value;
            app.ADDTIME=false;
            app.FromSlider.Value=0;
            app.ToSlider.Value=100;
            app.xmin.Value=0;
            app.deadpts=app.deadtime.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DCViewer.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {584, 584};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: LoaddatafileButton
        function LoaddatafileButtonPushed(app, event)
            startupFcn(app);
            [app.FileName,PathName] = uigetfile({'*.txt;*.csv'},'File Selector');
            if ((app.FileName==0) | (app.modelLOADED ==false))
                app.FILELOADED=false;
                return
            end        
            app.FileName
            app.curfile=fullfile(PathName,app.FileName);

            if verLessThan('matlab', '9.6')
                app.data = csvread(app.curfile);
            else
                app.data = readmatrix(app.curfile);
            end
            
            app.FILELOADED=true;
            
            postfileloaded(app);
            val=istime(app);
            if(val>0)                
                %keeping public and private version which sounds wrong :-)
                app.si=val;
                app.dt.Value=val;              
            else
                addtime(app);
            end
            decimate(app)
            e=msgbox("sorry this may take sometime in silence!","Running");
            app.data=double(pyrunfile("MLpredictorTF2.py","c", mymodel=app.model,  ...
                data=app.data, limit2=app.YesBin.Value));
            app.rawdata=app.data; %safe copy
            delete(e);
            dcols=length(app.data(1,:));
            if dcols==2
                app.OnePlot.Value=true;
            else
                app.OnePlot.Value=false;
            end
            %This should be unecessary now...
            app.FILELOADED=true;
            app.saveresultsButton.Visible=true;
            app.IDLonlyCheckBox.Visible=true;
            
            val=istime(app);
            if(val>0)
                app.ADDTIME=false;
                %keeping public and private version which sounds wrong :-)
                app.si=val;
                app.dt.Value=val;              
            else
                app.ADDTIME=true;
            end
            chooseplot(app);
            
        end

        % Value changed function: DecimatetoEditField
        function DecimatetoEditFieldValueChanged(app, event)
            app.decim = app.DecimatetoEditField.Value;
            decimate(app);      
            if app.OnePlot.Value==true
                %Todo: Not really sorted this yet.
                plot1ch(app);
            else 
                plot2ch(app);
            end

        end

        % Callback function
        function IsthereatimecolButtonGroupSelectionChanged(app, event)
            selectedButton = app.IsthereatimecolButtonGroup.SelectedObject;
                if app.YesTime==true
                    app.NoTime=false;
                    app.ADDTIME=false;
                end
                if app.NoTime==true
                    app.YesTime=false;
                    app.ADDTIME=true;
                end
        end

        % Value changed function: xmin
        function xminValueChanged(app, event)
            if app.FILELOADED==true
                if app.newscale==true
                    maxX=app.xmax.Value;
                else
                    maxX=app.rawAxis.XLim(2);
                end
                value = app.xmin.Value;
                if value >maxX
                    value=0;
                end
                if app.xmin.Value>app.xmax.Value
                    app.xmin.Value=0;
                    app.FromSlider.Value=0;               
                end 
                setaxis(app, value, maxX);  
                
                app.FromSlider.Value=100*value/app.maxtime;
                app.newscale=true;
            else
                f = msgbox("Load a model then a datafile first!","Error 101",'Error','error');
            end                   
        end

        % Value changed function: xmax
        function xmaxValueChanged(app, event)
           if app.FILELOADED==true
               if app.newscale==true
                    minX=app.xmin.Value;
                else
                    minX=app.rawAxis.XLim(1);
                    app.rawAxis.XLim;
                end
                value = app.xmax.Value;
                if value<minX
                    value=app.maxtime;
                end
                if value>app.maxtime
                    value=app.maxtime;
                end
                setaxis(app, minX, value);
    
                app.ToSlider.Value=100*value/app.maxtime;
                app.newscale=true;

            else
                f = msgbox("Load a model then a datafile first!","Error 101",'Error','error');
            end    
        end

        % Value changed function: FromSlider
        function FromSliderValueChanged(app, event)
            if app.FILELOADED==true
                value = app.FromSlider.Value;
                if app.newscale==true
                    maxX=app.xmax.Value;
                else
                    maxX=app.rawAxis.XLim(2);
                end
                
                app.xmin.Value=value*app.maxtime/100;
                
                if app.xmin.Value>app.xmax.Value
                    app.xmin.Value=0;
                    app.FromSlider.Value=0;               
                end
                setaxis(app,app.xmin.Value, maxX);    
                app.newscale=true;
            else
                f = msgbox("Load a model then a datafile first!","Error 101",'Error','error');
            end
            
        end

        % Value changed function: ToSlider
        function ToSliderValueChanged(app, event)
            if app.FILELOADED==true
                value = app.ToSlider.Value;
                if app.newscale==true
                    minX=app.xmin.Value;
                else
                    minX=app.rawAxis.XLim(1);
                end
                
                app.xmax.Value=value*app.maxtime/100;
                if app.xmax.Value<app.xmin.Value
                    app.xmax.Value=app.maxtime;
                    app.ToSlider.Value=100;
                    
                end
                setaxis(app,minX, app.xmax.Value)  
                app.newscale=true;
            else
                f = msgbox("Load a model then a datafile first!","Error 101",'Error','error');
            end
            
        end

        % Value changed function: dt
        function dtValueChanged(app, event)
            value = app.dt.Value;
            app.si=value;  
        end

        % Value changed function: duration
        function durationValueChanged(app, event)
            windowlen = app.duration.Value;
            if app.FILELOADED==true
                %convert to datapoints
                windowlen=windowlen*app.maxtime/100;
                startpt=app.start.Value*app.maxtime/100;
                if (startpt+windowlen)>app.maxtime
                    val=100*(app.maxtime-startpt)/app.maxtime;
                    if val>100
                        val=100;
                    end
                    app.duration.Value=val;
                end
                setaxis(app,startpt,startpt+windowlen);
                app.xmin.Value=startpt;
                app.xmax.Value=startpt+windowlen;
                val=100*startpt/app.maxtime;
                if val<0
                    val=0;
                end
                app.FromSlider.Value=val;
                val = 100*(startpt+windowlen)/app.maxtime;
                if val>100
                    val=100;
                end
                app.ToSlider.Value=val;
            else
                f = msgbox("Load a model then a datafile first!","Error 101",'Error','error');
                app.duration.Value=100;
            end
            
        end

        % Value changed function: start
        function startValueChanged(app, event)
            value = app.start.Value;   
            if app.FILELOADED==true
                %convert to datapoints
                windowlen=app.duration.Value*app.maxtime/100;
                startpt=value*app.maxtime/100;               
                if (startpt+windowlen) > app.maxtime
                    val=100*(app.maxtime-windowlen)/app.maxtime;
                    if val<0
                        val=0;
                    end
                app.start.Value=val;
                end
                setaxis(app,startpt, startpt+windowlen )
  
                app.xmin.Value=startpt;
                app.xmax.Value=startpt+windowlen;
                val=100*startpt/app.maxtime;
                if val<0
                    val=0;
                end
                app.FromSlider.Value=val;
                val = 100*(startpt+windowlen)/app.maxtime;
                if val>100
                    val=100;
                end
                app.ToSlider.Value=val;
                app.newscale=true;
            else
                app.start.Value=0;
                f = msgbox("Load a model then a datafile first!","Error 101",'Error','error');
            end
        end

        % Button pushed function: LoadmodelButton
        function LoadmodelButtonPushed(app, event)
            [MFileName,PathName] = uigetfile({'*.h5'},'File Selector');
            if MFileName==0
                app.modelLOADED=false;
                return
            end        
            app.LoaddatafileButton.Visible=true;
            app.FirstLoadmodelLabel.Visible=false;
            app.NowloadfileLabel.Visible=true;
            app.model=fullfile(PathName,MFileName);
            app.modelLOADED=true;
            
        end

        % Button pushed function: ApplyDeadtimeButton
        function ApplyDeadtimeButtonPushed(app, event)
            if app.FILELOADED==true
                app.deadpts=app.deadtime.Value;
                if app.deadpts>0
                    last=length(app.rawdata(:,1))-app.deadpts;
                    newdata=app.rawdata;
                    for ii = 1:app.deadpts:last
                        val=mode(app.data(ii:ii+app.deadpts,3));
                        for jj=ii:ii+app.deadpts
                            newdata(jj,3)=val;
                        end
                    end
                    app.data=newdata;
                else
                    app.data=app.rawdata;
                end
                chooseplot(app);
            end
        end

        % Value changed function: deadtime
        function deadtimeValueChanged(app, event)
            app.deadpts = app.deadtime.Value;
            ApplyDeadtimeButtonPushed(app, event);
        end

        % Button pushed function: saveresultsButton
        function saveresultsButtonPushed(app, event)
            [filepath,name,ext] = fileparts(app.FileName);
            name=strcat(name,"res",ext);
            outfile=fullfile(filepath,name);
            [file,path,~] = uiputfile(outfile);
            if app.IDLonlyCheckBox.Value==true
                savedata=app.data(:,3);
            else
                savedata=app.data;
            end
            if file==0
                return;
            else
                outfile=fullfile(path,file);
                writematrix(savedata,outfile);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DCViewer = uifigure('Visible', 'off');
            app.DCViewer.Name = 'DC Viewer';
            app.DCViewer.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.DCViewer);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Get Files';
            app.LoaddatafileButton = uibutton(app.LeftPanel, 'push');
            app.LoaddatafileButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatafileButtonPushed, true);
            app.LoaddatafileButton.Visible = 'off';
            app.LoaddatafileButton.Tooltip = {'Load the data file to analyse'};
            app.LoaddatafileButton.Text = 'Load data file';
            app.DecimatetoEditFieldLabel = uilabel(app.LeftPanel);
            app.DecimatetoEditFieldLabel.Visible = 'off';
            app.DecimatetoEditFieldLabel.Text = 'Decimate to';
            app.DecimatetoEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DecimatetoEditField.ValueChangedFcn = createCallbackFcn(app, @DecimatetoEditFieldValueChanged, true);
            app.DecimatetoEditField.Visible = 'off';
            app.DecimatetoEditField.Value = 1;
            app.Notes = uieditfield(app.LeftPanel, 'text');
            app.Notes.Value = 'No data file loaded';
            app.DatacolumnsButtonGroup = uibuttongroup(app.LeftPanel);
            app.DatacolumnsButtonGroup.Title = 'Data columns?';
            app.OnePlot = uiradiobutton(app.DatacolumnsButtonGroup);
            app.OnePlot.Text = ' One';
            app.TwoPlot = uiradiobutton(app.DatacolumnsButtonGroup);
            app.TwoPlot.Text = 'Two';
            app.TwoPlot.Value = true;
            app.ThreePlot = uiradiobutton(app.DatacolumnsButtonGroup);
            app.ThreePlot.Text = 'Three';
            app.xminEditFieldLabel = uilabel(app.LeftPanel);
            app.xminEditFieldLabel.Text = 'xmin';
            app.xmin = uieditfield(app.LeftPanel, 'numeric');
            app.xmin.Limits = [0 Inf];
            app.xmin.ValueChangedFcn = createCallbackFcn(app, @xminValueChanged, true);
            app.xmaxEditFieldLabel = uilabel(app.LeftPanel);
            app.xmaxEditFieldLabel.Text = 'xmax';
            app.xmax = uieditfield(app.LeftPanel, 'numeric');
            app.xmax.Limits = [0 Inf];
            app.xmax.ValueChangedFcn = createCallbackFcn(app, @xmaxValueChanged, true);
            app.FromSliderLabel = uilabel(app.LeftPanel);
            app.FromSliderLabel.Text = 'From';
            app.FromSlider = uislider(app.LeftPanel);
            app.FromSlider.ValueChangedFcn = createCallbackFcn(app, @FromSliderValueChanged, true);
            app.ToLabel = uilabel(app.LeftPanel);
            app.ToLabel.Text = 'To';
            app.ToSlider = uislider(app.LeftPanel);
            app.ToSlider.ValueChangedFcn = createCallbackFcn(app, @ToSliderValueChanged, true);
            app.ToSlider.Value = 100;
            app.start = uiknob(app.LeftPanel, 'continuous');
            app.start.ValueChangedFcn = createCallbackFcn(app, @startValueChanged, true);
            app.start.Tooltip = {'start value for scrolling'};
            app.duration = uiknob(app.LeftPanel, 'continuous');
            app.duration.ValueChangedFcn = createCallbackFcn(app, @durationValueChanged, true);
            app.duration.Tooltip = {'length of scroll window'};
            app.duration.Value = 100;
            app.CustomdtsLabel = uilabel(app.LeftPanel);
            app.CustomdtsLabel.Text = 'Custom dt (s)';
            app.dt = uieditfield(app.LeftPanel, 'numeric');
            app.dt.ValueChangedFcn = createCallbackFcn(app, @dtValueChanged, true);
            app.dt.Value = 1e-05;
            app.LoadmodelButton = uibutton(app.LeftPanel, 'push');
            app.LoadmodelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmodelButtonPushed, true);
            app.LoadmodelButton.Text = 'Load model';
            app.FirstLoadmodelLabel = uilabel(app.LeftPanel);
            app.FirstLoadmodelLabel.Text = 'First: Load model';
            app.Binarize = uibuttongroup(app.LeftPanel);
            app.Binarize.Tooltip = {'Constraint to just open and closed?'};
            app.Binarize.Title = 'Binarize?';
            app.YesBin = uiradiobutton(app.Binarize);
            app.YesBin.Text = ' Yes';
            app.YesBin.Value = true;
            app.NoBin = uiradiobutton(app.Binarize);
            app.NoBin.Text = ' No';
            app.ApplyDeadtimeButton = uibutton(app.LeftPanel, 'push');
            app.ApplyDeadtimeButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyDeadtimeButtonPushed, true);
            app.ApplyDeadtimeButton.Visible = 'off';
            app.ApplyDeadtimeButton.Text = 'Apply Deadtime';
            app.deadtime = uieditfield(app.LeftPanel, 'numeric');
            app.deadtime.RoundFractionalValues = 'on';
            app.deadtime.ValueChangedFcn = createCallbackFcn(app, @deadtimeValueChanged, true);
            app.deadtime.Visible = 'off';
            app.deadtime.Value = 2;
            app.NowloadfileLabel = uilabel(app.LeftPanel);
            app.NowloadfileLabel.Visible = 'off';
            app.NowloadfileLabel.Text = 'Now load file';
            app.saveresultsButton = uibutton(app.LeftPanel, 'push');
            app.saveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @saveresultsButtonPushed, true);
            app.saveresultsButton.Visible = 'off';
            app.saveresultsButton.Text = 'save results';
            app.IDLonlyCheckBox = uicheckbox(app.LeftPanel);
            app.IDLonlyCheckBox.Visible = 'off';
            app.IDLonlyCheckBox.Text = 'IDL only';
            app.RightPanel = uipanel(app.GridLayout);
            app.rawAxis = uiaxes(app.RightPanel);
            title(app.rawAxis, 'Typically Raw')
            ylabel(app.rawAxis, 'Y')
            app.idealAxis = uiaxes(app.RightPanel);
            title(app.idealAxis, 'Typically ideal')
            ylabel(app.idealAxis, 'Y')
            app.extraAxis = uiaxes(app.RightPanel);
            title(app.extraAxis, 'Extra Axis')
            xlabel(app.extraAxis, 'time (s)')
            ylabel(app.extraAxis, 'Y')
            % Show the figure after all components are created
            app.DCViewer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DC_predictor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DCViewer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DCViewer)
        end
    end
end